classdef depi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FsEditField         matlab.ui.control.NumericEditField
        FsEditFieldLabel    matlab.ui.control.Label
        RUNButton           matlab.ui.control.Button
        SNRSlider           matlab.ui.control.RangeSlider
        SNRSliderLabel      matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        DropDownLabel       matlab.ui.control.Label
        FreqEditField       matlab.ui.control.NumericEditField
        FreqEditFieldLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [snrVec, berVec] = runBERsimulation(app, fc, fs, snrMax, mode)

            N      = 1e5;
            snrVec = 0:2:snrMax;
            berVec = zeros(size(snrVec));
            data   = randi([0 1], N, 1);
            key    = randi([0 1], N, 1);
            enc    = bitxor(data, key);

            for k = 1:length(snrVec)
                % 1) Modulare
                switch mode
                    case 'ASK'
                        tx = enc;
                    case 'DSB-SC'
                        t   = (0:N-1)'/fs; %#ok<NASGU>
                        carrier = cos(2*pi*fc*t);
                        tx = (2*enc-1).*carrier;
                    case 'BPSK'
                        tx = 2*enc - 1;
                    case '4-QAM'
                        % exemplu simplu 4-QAM
                        bits2 = reshape(enc,2,[])';
                        symb  = bi2de(bits2,'left-msb');
                        tx    = qammod(symb,4,'UnitAveragePower',true);
                    otherwise
                        error('Modulație necunoscută');
                end

                % 2) Canal AWGN
                rx = awgn(tx, snrVec(k), 'measured');

                % 3) Demodulare & Decriptare
                switch mode
                    case 'ASK'
                        rec = rx > 0.5;
                    case 'DSB-SC'
                        t   = (0:N-1)'/fs;
                        carrier = cos(2*pi*fc*t);
                        rec = (rx .* carrier) > 0;
                    case 'BPSK'
                        rec = rx > 0;
                    case '4-QAM'
                        recSym = qamdemod(rx,4,'UnitAveragePower',true);
                        bitsHat= de2bi(recSym,2,'left-msb')';
                        rec = bitsHat(:);
                end
                dec = bitxor(rec, key);

                % 4) Calcul BER
                berVec(k) = sum(data~=dec)/N;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RunButtonPushed(app, event)
            % 1. Citește parametrii din UI
            fc   = app.FreqEditField.Value;         % frecvența semnalului
            snr  = app.SNRSlider.Value;         % nivelul SNR
            mode = app.DropDown.Value;       % tipul modulației
            fs = app.FsEditField.Value;

            % 2. Apelează funcția de simulare (scrisă separat)
            [snrVec, berVec] = runBERsimulation(app, fc, fs, snr, mode);

            % 3. Desenează pe UIAxes
            plot(app.UIAxes, snrVec, berVec, '-o','LineWidth',1.5);
            set(app.UIAxes, 'YScale','log');
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'SNR [dB]');
            ylabel(app.UIAxes,'BER');
            title(app.UIAxes, sprintf('BER vs SNR - modulație %s', mode));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FreqEditFieldLabel = uilabel(app.UIFigure);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'ASK', 'DSB-SC', 'BPSK', '4-QAM'};
            app.DropDown.Value = 'ASK';
            app.SNRSliderLabel = uilabel(app.UIFigure);
            app.SNRSliderLabel.Text = 'SNR';
            app.SNRSlider = uislider(app.UIFigure, 'range');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.FsEditFieldLabel = uilabel(app.UIFigure);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = depi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end